classdef WayPointsAlongTransect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        SaveDepthButton              matlab.ui.control.Button
        EnterstartandendpositionoftransectLabel  matlab.ui.control.Label
        WayPointsalongTransectLabel  matlab.ui.control.Label
        ClearTransectButton          matlab.ui.control.Button
        ComputeDistanceButton        matlab.ui.control.Button
        gcEditField                  matlab.ui.control.NumericEditField
        gcEditFieldLabel             matlab.ui.control.Label
        DistancenmrhEditField        matlab.ui.control.NumericEditField
        DistancenmrhEditFieldLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        InsertProfileButton          matlab.ui.control.Button
        SaveWaypointsButton          matlab.ui.control.Button
        ComputeWaypointsButton       matlab.ui.control.Button
        SectionSettingsPanel         matlab.ui.container.Panel
        TrackTypeButtonGroup         matlab.ui.container.ButtonGroup
        GreatCirclegcButton          matlab.ui.control.RadioButton
        RhombusLinerhButton          matlab.ui.control.RadioButton
        SpacingButtonGroup           matlab.ui.container.ButtonGroup
        LatCheckBox                  matlab.ui.control.CheckBox
        MinutesButton                matlab.ui.control.RadioButton
        NauticalMilesButton          matlab.ui.control.RadioButton
        SpacingValueEditField        matlab.ui.control.NumericEditField
        SpacingValueEditFieldLabel   matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        A % Table for Start and End Location
        distrh %  rhombus distance in nautical miles
        distgc %  great circle distance in nautical miles
        azrh %
        azgc %
        pn % startup path
        latgrd % gebco latitudeect_start_stop
        longrd % gebco longitude
        Zgrd % gebco topographic on minute grid 10801 x 20601
        dist_wp % spacing between waypoints
        latsec % 
        lonsec % positions of computed waypoints in equal spacing along transect
        X % distance of latsec, lonsec from start location of the transect
        WD % water depth at latsec, lonsec 
        nstat % number of waypoints computed along transect
        % along rhombus line
        x_rh % distance of high resolution water depth profile
        wdepth_rh % high resolution water depth
        % along greate circle
        x_gc % distance of high resolution water depth profile
        wdepth_gc % high resolution water depth
        lattrk_rh  % lat/lon corresponding to x_rh, and 
        lontrk_rh % 
        lattrk_gc  % lat/lon corresponding to x_hc, and 
        lontrk_gc % 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Table for Start- and End Location of the Trasenct
                        sz = [2 3];
            varTypes = {'string','string','string'};
            varNames = {'StartEnd','Latitude','Longitude'};
            app.A = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            app.A.StartEnd(1) = "Start Location";
            app.A.Latitude(1) = "0";
            app.A.Longitude(1) = "0";
            app.A.StartEnd(2) = "End Location";
            app.A.Latitude(2) = "0";
            app.A.Longitude(2) = "0";
            app.UITable.Data = app.A;
            % check path 
            app.pn = cd;
            % Load GEBCO 1 minute topography: Find the download link in
            % PDF. The download is a netCDF file. User GRIDONEnc2mat.m to
            % convert netCDF into a MAT-file
            % Chapter Installation, item 6:
            grdfile = fullfile(app.pn,'Dataset','GRIDONE_2D.mat');
            load(grdfile);
            app.latgrd = lat;
            app.longrd = lon;
            app.Zgrd = Z;
            % latgrd, longrd, zgrd used in update_DateTime
            clear lat lon Z;
            % disable buttons:
            app.ComputeDistanceButton.Enable = "off";
            app.ClearTransectButton.Enable = "off";
            app.SaveDepthButton.Enable = "off";
            app.ComputeWaypointsButton.Enable = "off";
            app.SaveWaypointsButton.Enable = "off";
            app.InsertProfileButton.Enable = "off";
        end

        % Button pushed function: ComputeWaypointsButton
        function ComputeWaypointsButtonPushed(app, event)
            app.A = app.UITable.Data;
            % latitude or longitude can be entered in degrees and minutes
            % or in decimal degrees (-) == south or west; e.g. 
            % -60 30.0 or -60.5
            latstr1 = app.A.Latitude(1);     
            lonstr1 = app.A.Longitude(1);
            latstr2 = app.A.Latitude(2);     
            lonstr2 = app.A.Longitude(2);
            % use str2num to identify input format (decimal degrees or
            % degrees and minutes), see mapping tool box: dm2degrees.m 
            lat1 = str2num(latstr1);
            if length(lat1) == 2 % for input in degree minute
                LAT(1) = dm2degrees(lat1);
            else % for input in decimal degrees
                LAT(1) = lat1;
            end
                lon1 = str2num(lonstr1);
                if length(lon1) == 2 % for input in degree minute
                    LON(1) = dm2degrees(lon1);
                else
                    LON(1) = lon1;
                end
            lat2 = str2num(latstr2);
            if length(lat2) == 2 % for input in degree minute
                LAT(2) = dm2degrees(lat2);
            else
                LAT(2) = lat2;
            end
                lon2 = str2num(lonstr2);
                if length(lon2) == 2 % for input in degree minute
                    LON(2) = dm2degrees(lon2);
                else
                    LON(2) = lon2;
                end
            if app.GreatCirclegcButton.Value
                rhgc ="gc";
            else
                rhgc ="rh";
            end
            if app.NauticalMilesButton.Value
                flg = 0;
            else
                flg = 1;
            end
            if app.LatCheckBox.Value
                latORlon = 1;
            else
                latORlon = 0;
            end
            
            dist_stat = app.SpacingValueEditField.Value;
            % see transect.m
            [app.latsec,app.lonsec,app.X,app.WD,app.nstat] = ...
                transect(app.latgrd,app.longrd,app.Zgrd,LAT,LON,rhgc,dist_stat,flg,latORlon);

            hold(app.UIAxes,"on")
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end

                plot(app.UIAxes,app.X,app.WD);
                for i = 1:app.nstat
                    plot(app.UIAxes,[app.X(i) app.X(i)],[round(max(app.WD)) app.WD(i)],"-r");
                end

            hold(app.UIAxes,"off")
            % plot map of transect
            cla(app.UIAxes2);
            % mercator or stereo projection; see plot_map_transect_stereo
            % and plot_map_transect_mercator
            % imshow was used to display the map in the app. therfore the plot first
            % creates a figure which was saved as a png, using a temporary
            % file tmpgeoimg.png
            if max(app.latsec) > 86
                plot_map_transect_stereo(app.latsec,app.lonsec);
                a = imread(fullfile(app.pn,'tmpgeoimg.png')); 
                imshow(a, 'Parent' , app.UIAxes2);
            else
                plot_map_transect_mercator(app.latsec,app.lonsec);
                a = imread(fullfile(app.pn,'tmpgeoimg.png')); 
                imshow(a, 'Parent' , app.UIAxes2);
            end
            app.SaveWaypointsButton.Enable = "on";
            if app.RhombusLinerhButton.Value && app.NauticalMilesButton.Value
                app.InsertProfileButton.Enable = "on";
            else
                app.InsertProfileButton.Enable = "off";
            end

        end

        % Button pushed function: ComputeDistanceButton
        function ComputeDistanceButtonPushed(app, event)
            % compute the distance of the transect using "distance.m" form
            % the mapping tool box.
            app.A = app.UITable.Data;
            % latitude or longitude can be entered in degrees and minutes
            % or in decimal degrees (-) == south or west; e.g. 
            % -60 30.0 or -60.5
            latstr1 = app.A.Latitude(1);     
            lonstr1 = app.A.Longitude(1);
            latstr2 = app.A.Latitude(2);     
            lonstr2 = app.A.Longitude(2);
            % use str2num:
            lat1 = str2num(latstr1);
            if length(lat1) == 2 % for input in degree minute
                LAT1 = dm2degrees(lat1);
            else
                LAT1 = lat1;
            end
                lon1 = str2num(lonstr1);
                if length(lon1) == 2 % for input in degree minute
                    LON1 = dm2degrees(lon1);
                else
                    LON1 = lon1;
                end
            lat2 = str2num(latstr2);
            if length(lat2) == 2 % for input in degree minute
                LAT2 = dm2degrees(lat2);
            else
                LAT2 = lat2;
            end
                lon2 = str2num(lonstr2);
                if length(lon2) == 2 % for input in degree minute
                    LON1 = dm2degrees(lon2);
                else
                    LON2 = lon2;
                end
            [dist,app.azrh] = distance('rh',LAT1, LON1, LAT2, LON2);
            app.distrh=deg2nm(dist);
            app.DistancenmrhEditField.Value = round(app.distrh,0);
            [dist,app.azgc] = distance('gc',LAT1, LON1, LAT2, LON2);
            app.distgc=deg2nm(dist);
            app.gcEditField.Value = round(app.distgc,0);
            
            % Depth profile along the transect; see depth_along_transect.m
            % The ocean depth was taken from GRIDONE_2D.mat - app.latgrd,
            % app.longrd,app.Zgrd see startupFcn. 
            [app.x_rh,app.wdepth_rh, ...
                app.x_gc,app.wdepth_gc,...
                app.lattrk_rh,app.lontrk_rh,...
                app.lattrk_gc,app.lontrk_gc] = ...
                depth_along_transect(app.latgrd,app.longrd,app.Zgrd, ...
                app.distrh,app.distgc,LAT1,LON1,LAT2,LON2);
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end
            app.SaveDepthButton.Enable = "on";
        end

        % Button pushed function: ClearTransectButton
        function ClearTransectButtonPushed(app, event)
            % disable buttons:
            app.ComputeDistanceButton.Enable = "off";
            app.ClearTransectButton.Enable = "off";
            app.SaveDepthButton.Enable = "off";
            app.ComputeWaypointsButton.Enable = "off";
            app.SaveWaypointsButton.Enable = "off";
            app.InsertProfileButton.Enable = "off";
            app.DistancenmrhEditField.Value = 0;
            app.gcEditField.Value = 0;

            app.A.Latitude(1) = "";
            app.A.Longitude(1) = "";
            app.A.Latitude(2) = "";
            app.A.Longitude(2) = "";
            app.UITable.Data = app.A;
            % clear map of transect (UIAxes2)
            hold(app.UIAxes2,"off");
            cla(app.UIAxes2,"reset");
            % clear depth profile
            hold(app.UIAxes,"off");
            cla(app.UIAxes,"reset");
            % reset spacing value to 0
            app.SpacingValueEditField.Value = 0;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % enable buttons:
            app.ComputeDistanceButton.Enable = "on";
            app.ClearTransectButton.Enable = "on";
        end

        % Value changed function: SpacingValueEditField
        function SpacingValueEditFieldValueChanged(app, event)
            app.dist_wp = app.SpacingValueEditField.Value;
            % disable buttons:
            app.ComputeWaypointsButton.Enable = "on";
            % clear map of transect (UIAxes2)
            hold(app.UIAxes2,"off");
            cla(app.UIAxes2,"reset");
            % clear depth profile
            hold(app.UIAxes,"off");
            cla(app.UIAxes,"reset");
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end
        end

        % Selection changed function: TrackTypeButtonGroup
        function TrackTypeButtonGroupSelectionChanged(app, event)
            app.dist_wp = app.SpacingValueEditField.Value;
            % disable buttons:
            app.ComputeWaypointsButton.Enable = "on";
            % clear map of transect (UIAxes2)
            hold(app.UIAxes2,"off");
            cla(app.UIAxes2,"reset");
            % clear depth profile
            hold(app.UIAxes,"off");
            cla(app.UIAxes,"reset");
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end
        end

        % Selection changed function: SpacingButtonGroup
        function SpacingButtonGroupSelectionChanged(app, event)
            app.dist_wp = app.SpacingValueEditField.Value;
            % disable buttons:
            app.ComputeWaypointsButton.Enable = "on";
            % clear map of transect (UIAxes2)
            hold(app.UIAxes2,"off");
            cla(app.UIAxes2,"reset");
            % clear depth profile
            hold(app.UIAxes,"off");
            cla(app.UIAxes,"reset");
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end
        end

        % Button pushed function: SaveWaypointsButton
        function SaveWaypointsButtonPushed(app, event)
        % save way points app.latsec and app.lonsec into text file:
        [fname,pname] = uiputfile('*.txt','save your section');
        outfile = fullfile(pname,fname);
        fidwp = fopen(outfile,'w');
            for i = 1:length(app.latsec)
                fprintf(fidwp,'%9.4f\t%9.4f\n',app.latsec(i),app.lonsec(i));
            end
        fclose(fidwp);
        end

        % Button pushed function: InsertProfileButton
        function InsertProfileButtonPushed(app, event)
            
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current           
            [x,~,~] = ginput(1);                        % run ginput
            app.UIFigure.HandleVisibility = fhv;        % return original state

            if app.GreatCirclegcButton.Value
                rhgc ="gc";
            else
                rhgc ="rh";
            end
            % see add_wp_to_transect.m
            [app.latsec,app.lonsec,app.X,app.WD,app.nstat,i1] = add_wp_to_transect(x, ...
                rhgc,app.latsec,app.lonsec,app.X,app.WD,app.nstat,app.latgrd,app.longrd,app.Zgrd);

            % plot a gree line:
            hold(app.UIAxes,"on")
            plot(app.UIAxes,[app.X(i1+1) app.X(i1+1)],[round(max(app.WD)) app.WD(i1+1)],"-g");
            hold(app.UIAxes,"off")

            % plot map of transect
            cla(app.UIAxes2);
            % mercator or stereo projection
            if max(app.latsec) > 86
                plot_map_transect_stereo(app.latsec,app.lonsec);
                a = imread(fullfile(app.pn,'tmpgeoimg.png')); 
                imshow(a, 'Parent' , app.UIAxes2);
            else
                plot_map_transect_mercator(app.latsec,app.lonsec);
                a = imread(fullfile(app.pn,'tmpgeoimg.png')); 
                imshow(a, 'Parent' , app.UIAxes2);
            end

            app.SaveWaypointsButton.Enable = "on";
            app.InsertProfileButton.Enable = "on";

        end

        % Value changed function: LatCheckBox
        function LatCheckBoxValueChanged(app, event)
            app.dist_wp = app.SpacingValueEditField.Value;
            % disable buttons:
            app.ComputeWaypointsButton.Enable = "on";
            % clear map of transect (UIAxes2)
            hold(app.UIAxes2,"off");
            cla(app.UIAxes2,"reset");
            % clear depth profile
            hold(app.UIAxes,"off");
            cla(app.UIAxes,"reset");
            if app.GreatCirclegcButton.Value
                plot(app.UIAxes,app.x_gc,app.wdepth_gc,"k-");
            else
                plot(app.UIAxes,app.x_rh,app.wdepth_rh,"k-");
            end
        end

        % Button pushed function: SaveDepthButton
        function SaveDepthButtonPushed(app, event)
            % save depth profile of transect into text file:
            % make filename from start of location:
            latstr1 = app.A.Latitude(1);     
            lonstr1 = app.A.Longitude(1);
            lat1 = str2num(latstr1);
            if length(lat1) == 2 % for input in degree minute
                LAT1 = dm2degrees(lat1);
            else
                LAT1 = lat1;
            end
            lon1 = str2num(lonstr1);
            if length(lon1) == 2 % for input in degree minute
                LON1 = dm2degrees(lon1);
            else
                LON1 = lon1;
            end
            % rhombus
            fname = ['sec_rh_' num2str(round(LAT1)) '_' num2str(round(LON1)) '.txt'];
            pname = uigetdir(app.pn);
            outfile = fullfile(pname,fname);
            fid = fopen(outfile,'w');
                for i = 1:length(app.lattrk_rh)-1
                    fprintf(fid,'%9.4f\t%9.4f\t%9.1f\t%9.1f\n', ...
                        app.lattrk_rh(i), ...
                        app.lontrk_rh(i),...
                        app.x_rh(i), ...
                        app.wdepth_rh(i));
                end
            fclose(fid);
            clear outfile fname;
            % great cirle
            fname = ['sec_gc_' num2str(round(LAT1)) '_' num2str(round(LON1)) '.txt'];
            outfile = fullfile(pname,fname);
            fid = fopen(outfile,'w');
                for i = 1:length(app.lattrk_gc)-1
                    fprintf(fid,'%9.4f\t%9.4f\t%9.1f\t%9.1f\n', ...
                        app.lattrk_gc(i), ...
                        app.lontrk_gc(i),...
                        app.x_gc(i), ...
                        app.wdepth_gc(i));
                end
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Section')
            xlabel(app.UIAxes, 'Distance (nm)')
            ylabel(app.UIAxes, 'Waterdepth (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Map of section')
            app.SectionSettingsPanel = uipanel(app.UIFigure);
            app.SectionSettingsPanel.Title = 'Section Settings';
            app.SpacingValueEditFieldLabel = uilabel(app.SectionSettingsPanel);
            app.SpacingValueEditFieldLabel.Text = 'Spacing  Value';
            app.SpacingValueEditField = uieditfield(app.SectionSettingsPanel, 'numeric');
            app.SpacingValueEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingValueEditFieldValueChanged, true);
            app.SpacingButtonGroup = uibuttongroup(app.SectionSettingsPanel);
            app.SpacingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpacingButtonGroupSelectionChanged, true);
            app.SpacingButtonGroup.Title = 'Spacing';
            app.NauticalMilesButton = uiradiobutton(app.SpacingButtonGroup);
            app.NauticalMilesButton.Text = 'Nautical Miles';
            app.NauticalMilesButton.Value = true;
            app.MinutesButton = uiradiobutton(app.SpacingButtonGroup);
            app.MinutesButton.Text = 'Minutes';
            app.LatCheckBox = uicheckbox(app.SpacingButtonGroup);
            app.LatCheckBox.ValueChangedFcn = createCallbackFcn(app, @LatCheckBoxValueChanged, true);
            app.LatCheckBox.Text = ' Lat ';
            app.LatCheckBox.Value = true;
            app.TrackTypeButtonGroup = uibuttongroup(app.SectionSettingsPanel);
            app.TrackTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrackTypeButtonGroupSelectionChanged, true);
            app.TrackTypeButtonGroup.Title = 'Track Type';
            app.RhombusLinerhButton = uiradiobutton(app.TrackTypeButtonGroup);
            app.RhombusLinerhButton.Text = ' Rhombus Line (rh)';
            app.RhombusLinerhButton.Value = true;
            app.GreatCirclegcButton = uiradiobutton(app.TrackTypeButtonGroup);
            app.GreatCirclegcButton.Text = 'Great Circle (gc)';
            app.ComputeWaypointsButton = uibutton(app.UIFigure, 'push');
            app.ComputeWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeWaypointsButtonPushed, true);
            app.ComputeWaypointsButton.Text = 'Compute Waypoints';
            app.SaveWaypointsButton = uibutton(app.UIFigure, 'push');
            app.SaveWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWaypointsButtonPushed, true);
            app.SaveWaypointsButton.Text = 'Save Waypoints';
            app.InsertProfileButton = uibutton(app.UIFigure, 'push');
            app.InsertProfileButton.ButtonPushedFcn = createCallbackFcn(app, @InsertProfileButtonPushed, true);
            app.InsertProfileButton.Text = 'Insert Profile';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Latitude'; 'Longitude'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.DistancenmrhEditFieldLabel = uilabel(app.UIFigure);
            app.DistancenmrhEditFieldLabel.Text = 'Distance (nm)   rh';
            app.DistancenmrhEditField = uieditfield(app.UIFigure, 'numeric');
            app.gcEditFieldLabel = uilabel(app.UIFigure);
            app.gcEditFieldLabel.Text = 'gc';
            app.gcEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputeDistanceButton = uibutton(app.UIFigure, 'push');
            app.ComputeDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDistanceButtonPushed, true);
            app.ComputeDistanceButton.Text = 'Compute Distance';
            app.ClearTransectButton = uibutton(app.UIFigure, 'push');
            app.ClearTransectButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransectButtonPushed, true);
            app.ClearTransectButton.Text = 'Clear Transect';
            app.WayPointsalongTransectLabel = uilabel(app.UIFigure);
            app.WayPointsalongTransectLabel.Text = 'Way Points along Transect';
            app.EnterstartandendpositionoftransectLabel = uilabel(app.UIFigure);
            app.EnterstartandendpositionoftransectLabel.Text = 'Enter start- and end-position of transect';
            app.SaveDepthButton = uibutton(app.UIFigure, 'push');
            app.SaveDepthButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDepthButtonPushed, true);
            app.SaveDepthButton.Text = 'Save Depth';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Author: Gerd Rohardt'; 'Contact: Gerd.Rohardt@ewetel.net'; 'Release: 15. August 2023'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WayPointsAlongTransect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end